classdef MBDTools < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MBDApp                matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        TestCaseGenTab        matlab.ui.container.Tab
        ModelTextAreaLabel    matlab.ui.control.Label
        ModelTextArea         matlab.ui.control.TextArea
        OpenModelButton       matlab.ui.control.Button
        OpenExcelFileButton   matlab.ui.control.Button
        TestDocTextAreaLabel  matlab.ui.control.Label
        TestDocTextArea       matlab.ui.control.TextArea
        OpenHarnessButton     matlab.ui.control.Button
        HarnessTextAreaLabel  matlab.ui.control.Label
        HarnessTextArea       matlab.ui.control.TextArea
        Button_4              matlab.ui.control.StateButton
        Button_5              matlab.ui.control.StateButton
        Tab2                  matlab.ui.container.Tab
        UITable               matlab.ui.control.Table
        MBDLabel              matlab.ui.control.Label
        Image                 matlab.ui.control.Image
    end

    
    properties (Access = private)
        testInfo        %The Information used to create test Harness
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            uifigureOnTop(app.MBDApp);
        end

        % Button pushed function: OpenExcelFileButton
        function OpenExcelFileButtonPushed(app, event)
            [fileName, pathName] = uigetfile({'*.xlsx'},'File Selector');
            fullPath = fullfile(pathName, fileName);
            tableContent = readtable(fullPath, "Sheet",1);
            app.UITable.Data = tableContent;
            app.testInfo.Doc = fullPath;
            app.testInfo.Model = 'bbb';
            disp(app.testInfo.Doc)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
%             selectedTab = app.TabGroup.SelectedTab;
%             app.testInfo.Doc = 'aaa'
%             app.testInfo.Model = 'bbb'
            
        end

        % Button pushed function: OpenHarnessButton
        function OpenHarnessButtonPushed(app, event)
            harnessList = sltest.harness.find(bdroot)
            f = uifigure('Name','Testharness List');
            uc = uicontrol(f, 'Style','listbox')
            uc.Position = [20 175 160 220];
            uc.String = harnessList.name
            uicontrol(uc)
            
        end

        % Button pushed function: OpenModelButton
        function OpenModelButtonPushed(app, event)
            
        end

        % Callback function
        function Button2Pushed(app, event)
            set(app.MBDApp,'WindowStyle','modal')
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            value = app.Button_4.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MBDApp = uifigure('Visible', 'off');
            app.MBDApp.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.MBDApp);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.TestCaseGenTab = uitab(app.TabGroup);
            app.TestCaseGenTab.Title = 'Test Case Gen';
            app.ModelTextAreaLabel = uilabel(app.TestCaseGenTab);
            app.ModelTextAreaLabel.Text = 'Model';
            app.ModelTextArea = uitextarea(app.TestCaseGenTab);
            app.OpenModelButton = uibutton(app.TestCaseGenTab, 'push');
            app.OpenModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenModelButtonPushed, true);
            app.OpenModelButton.Text = '...';
            app.OpenExcelFileButton = uibutton(app.TestCaseGenTab, 'push');
            app.OpenExcelFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenExcelFileButtonPushed, true);
            app.OpenExcelFileButton.Text = '...';
            app.TestDocTextAreaLabel = uilabel(app.TestCaseGenTab);
            app.TestDocTextAreaLabel.Text = 'TestDoc';
            app.TestDocTextArea = uitextarea(app.TestCaseGenTab);
            app.OpenHarnessButton = uibutton(app.TestCaseGenTab, 'push');
            app.OpenHarnessButton.ButtonPushedFcn = createCallbackFcn(app, @OpenHarnessButtonPushed, true);
            app.OpenHarnessButton.Text = '...';
            app.HarnessTextAreaLabel = uilabel(app.TestCaseGenTab);
            app.HarnessTextAreaLabel.Text = 'Harness';
            app.HarnessTextArea = uitextarea(app.TestCaseGenTab);
            app.Button_4 = uibutton(app.TestCaseGenTab, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = 'Button';
            app.Button_5 = uibutton(app.TestCaseGenTab, 'state');
            app.Button_5.Text = 'Button';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UITable = uitable(app.Tab2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.MBDLabel = uilabel(app.MBDApp);
            app.MBDLabel.Text = 'MBD工具箱';
            app.Image = uiimage(app.MBDApp);
            app.Image.ImageSource = '-54d68564cde2cd08.jpg';
            % Show the figure after all components are created
            app.MBDApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MBDTools

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MBDApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MBDApp)
        end
    end
end